classdef rotation_matrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        ParameterPanel     matlab.ui.container.Panel
        phiSlider          matlab.ui.control.Slider
        thetaSlider        matlab.ui.control.Slider
        psiSlider          matlab.ui.control.Slider
        xSlider            matlab.ui.control.Slider
        ySlider            matlab.ui.control.Slider
        zSlider            matlab.ui.control.Slider
        xSpinnerLabel      matlab.ui.control.Label
        xSpinner           matlab.ui.control.Spinner
        ySpinner           matlab.ui.control.Spinner
        ySpinnerLabel      matlab.ui.control.Label
        zSpinner           matlab.ui.control.Spinner
        zSpinnerLabel      matlab.ui.control.Label
        phiSpinner         matlab.ui.control.Spinner
        phiSpinnerLabel    matlab.ui.control.Label
        thetaSpinner       matlab.ui.control.Spinner
        thetaSpinnerLabel  matlab.ui.control.Label
        psiSpinner         matlab.ui.control.Spinner
        psiSpinnerLabel    matlab.ui.control.Label
        ResetButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        body_frame % Description
        earth_frame 
        bf_x = 0
        bf_y = 0
        bf_z = 0
        bf_phi = 0
        bf_theta = 0
        bf_psi = 0
    end
    methods (Access = private)
        % 座標系の作成
        % 全ての引数を0にした場合、ローカル座標系となる
        function frame_state = create_frame(app,x,y,z,phi,theta,psi)
            % 座標系のプロット
            frame_state.x = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','red','LineWidth',3);
            frame_state.y = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','blue','LineWidth',3);
            frame_state.z = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','green','LineWidth',3);
            update_frame(app,frame_state,x,y,z,phi,theta,psi);
        end
    
        % 機体固定座標系の更新
        % ローカル座標系(地図座標系)から見た機体固定系の座標と姿勢を更新する
        function update_frame(app,frame_state,x,y,z,phi,theta,psi)
            % ローカル座標系の定義
            ex = [1;0;0];
            ey = [0;1;0];
            ez = [0;0;1];
            % 三角関数の定義
            sin_phi = sin(phi);
            cos_phi = cos(phi);
            sin_theta = sin(theta);
            cos_theta = cos(theta);
            sin_psi = sin(psi);
            cos_psi = cos(psi);
            % 回転行列
            Rx = [1 0 0;0 cos_phi -sin_phi;0 sin_phi cos_phi];
            Ry = [cos_theta 0 sin_theta;0 1 0;-sin_theta 0 cos_theta];
            Rz = [cos_psi -sin_psi 0;sin_psi cos_psi 0;0 0 1];
            % 機体固定系の算出
            bx = Rz*Ry*Rx*ex;
            by = Rz*Ry*Rx*ey;
            bz = Rz*Ry*Rx*ez;
%             bx = Rx*Ry*Rz*ex;
%             by = Rx*Ry*Rz*ey;
%             bz = Rx*Ry*Rz*ez;
            % 座標系ベクトルの設定
            set(frame_state.x,'UData',bx(1),'VData',bx(2),'WData',bx(3),'XData',x,'YData',y,'ZData',z);
            set(frame_state.y,'UData',by(1),'VData',by(2),'WData',by(3),'XData',x,'YData',y,'ZData',z);
            set(frame_state.z,'UData',bz(1),'VData',bz(2),'WData',bz(3),'XData',x,'YData',y,'ZData',z);
        end
        function update_body_frame(app)
            % ローカル座標系の定義
            ex = [1;0;0];
            ey = [0;1;0];
            ez = [0;0;1];
            % 三角関数の定義
            sin_phi = sin(app.bf_phi);
            cos_phi = cos(app.bf_phi);
            sin_theta = sin(app.bf_theta);
            cos_theta = cos(app.bf_theta);
            sin_psi = sin(app.bf_psi);
            cos_psi = cos(app.bf_psi);
%             % 回転行列
%             Rx = [1 0 0;0 cos_phi -sin_phi;0 sin_phi cos_phi];
%             Ry = [cos_theta 0 sin_theta;0 1 0;-sin_theta 0 cos_theta];
%             Rz = [cos_psi -sin_psi 0;sin_psi cos_psi 0;0 0 1];
%             % 機体固定系の算出
%             bx = Rz*Ry*Rx*ex;
%             by = Rz*Ry*Rx*ey;
%             bz = Rz*Ry*Rx*ez;
            R = ([cos_psi -sin_psi 0;sin_psi cos_psi 0;0 0 1]...
                *[cos_theta 0 sin_theta;0 1 0;-sin_theta 0 cos_theta]...
                *[1 0 0;0 cos_phi -sin_phi;0 sin_phi cos_phi])';
            bx = R*ex;
            by = R*ey;
            bz = R*ez;
%             bx = Rx*Ry*Rz*ex;
%             by = Rx*Ry*Rz*ey;
%             bz = Rx*Ry*Rz*ez;
            % 座標系ベクトルの設定
            set(app.body_frame.x,'UData',bx(1),'VData',bx(2),'WData',bx(3),'XData',app.bf_x,'YData',app.bf_y,'ZData',app.bf_z);
            set(app.body_frame.y,'UData',by(1),'VData',by(2),'WData',by(3),'XData',app.bf_x,'YData',app.bf_y,'ZData',app.bf_z);
            set(app.body_frame.z,'UData',bz(1),'VData',bz(2),'WData',bz(3),'XData',app.bf_x,'YData',app.bf_y,'ZData',app.bf_z);
        end
        function frame_state = test_create(app)
            frame_state.x = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','red');
%             frame_state.y = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','blue');
%             frame_state.z = quiver3(app.UIAxes,0,0,0,0,0,0,0,'Color','green');
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes,'on');
            rotate3d(app.UIAxes,'on');
            xlabel(app.UIAxes,'x [m]','FontName','arial','FontSize',10)
            ylabel(app.UIAxes,'y [m]','FontName','arial','FontSize',10)
            zlabel(app.UIAxes,'z [m]','FontName','arial','FontSize',10)
            set(app.UIAxes,...
                'YDir','reverse',...
                'ZDir','reverse',...
                'XLim',[-4 4],...
                'YLim',[-4 4],...
                'ZLim',[-4 4]...
                );
            app.earth_frame = create_frame(app,0,0,0,0*pi/180,0*pi/180,0*pi/180);
            app.body_frame = create_frame(app,0,0,0,0*pi/180,0*pi/180,0*pi/180);
        end

        % Value changing function: phiSlider
        function phiSliderValueChanging(app, event)
            app.bf_phi = event.Value*pi/180;
            app.phiSpinner.Value = event.Value;
            update_body_frame(app);
        end

        % Value changing function: thetaSlider
        function thetaSliderValueChanging(app, event)
            app.bf_theta = event.Value*pi/180;
            app.thetaSpinner.Value = event.Value;
            update_body_frame(app);
        end

        % Value changing function: psiSlider
        function psiSliderValueChanging(app, event)
            app.bf_psi = event.Value*pi/180;
            app.psiSpinner.Value = event.Value;
            update_body_frame(app);
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            app.bf_x = event.Value;
            app.xSpinner.Value = app.bf_x;
            update_body_frame(app);
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            app.bf_y = event.Value;
            app.ySpinner.Value = app.bf_y;
            update_body_frame(app);
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            app.bf_z = event.Value;
            app.zSpinner.Value = app.bf_z;
            update_body_frame(app);
        end

        % Value changing function: xSpinner
        function xSpinnerValueChanging(app, event)
            app.bf_x = event.Value;
            app.xSlider.Value = app.bf_x;
            update_body_frame(app);
        end

        % Value changing function: ySpinner
        function ySpinnerValueChanging(app, event)
            app.bf_y = event.Value;
            app.ySlider.Value = app.bf_y;
            update_body_frame(app);
        end

        % Value changing function: zSpinner
        function zSpinnerValueChanging(app, event)
            app.bf_z = event.Value;
            app.zSlider.Value = app.bf_z;
            update_body_frame(app);
        end

        % Value changing function: phiSpinner
        function phiSpinnerValueChanging(app, event)
            app.bf_phi = event.Value*pi/180;
            app.phiSlider.Value = event.Value;
            update_body_frame(app);
        end

        % Value changing function: thetaSpinner
        function thetaSpinnerValueChanging(app, event)
            app.bf_theta = event.Value*pi/180;
            app.thetaSlider.Value = event.Value;
            update_body_frame(app);
        end

        % Value changing function: psiSpinner
        function psiSpinnerValueChanging(app, event)
            app.bf_psi = event.Value*pi/180;
            app.psiSlider.Value = event.Value;
            update_body_frame(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.bf_x = 0;
            app.bf_y = 0;
            app.bf_z = 0;
            app.bf_phi = 0;
            app.bf_theta = 0;
            app.bf_psi = 0;
            app.xSlider.Value = app.bf_x;
            app.ySlider.Value = app.bf_y;
            app.zSlider.Value = app.bf_z;
            app.phiSlider.Value = app.bf_phi;
            app.thetaSlider.Value = app.bf_theta;
            app.psiSlider.Value = app.bf_psi;
            app.xSpinner.Value = app.bf_x;
            app.ySpinner.Value = app.bf_y;
            app.zSpinner.Value = app.bf_z;
            app.phiSpinner.Value = app.bf_phi;
            app.thetaSpinner.Value = app.bf_theta;
            app.psiSpinner.Value = app.bf_psi;
            update_body_frame(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParameterPanel = uipanel(app.UIFigure);
            app.ParameterPanel.Title = 'Parameter';
            app.phiSlider = uislider(app.ParameterPanel);
            app.phiSlider.Limits = [-180 180];
            app.phiSlider.ValueChangingFcn = createCallbackFcn(app, @phiSliderValueChanging, true);
            app.thetaSlider = uislider(app.ParameterPanel);
            app.thetaSlider.Limits = [-180 180];
            app.thetaSlider.ValueChangingFcn = createCallbackFcn(app, @thetaSliderValueChanging, true);
            app.psiSlider = uislider(app.ParameterPanel);
            app.psiSlider.Limits = [-180 180];
            app.psiSlider.ValueChangingFcn = createCallbackFcn(app, @psiSliderValueChanging, true);
            app.xSlider = uislider(app.ParameterPanel);
            app.xSlider.Limits = [-10 10];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySlider = uislider(app.ParameterPanel);
            app.ySlider.Limits = [-10 10];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSlider = uislider(app.ParameterPanel);
            app.zSlider.Limits = [-10 10];
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.xSpinnerLabel = uilabel(app.ParameterPanel);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.ParameterPanel);
            app.xSpinner.ValueChangingFcn = createCallbackFcn(app, @xSpinnerValueChanging, true);
            app.xSpinner.Limits = [-10 10];
            app.ySpinner = uispinner(app.ParameterPanel);
            app.ySpinner.ValueChangingFcn = createCallbackFcn(app, @ySpinnerValueChanging, true);
            app.ySpinner.Limits = [-10 10];
            app.ySpinnerLabel = uilabel(app.ParameterPanel);
            app.ySpinnerLabel.Text = 'y';
            app.zSpinner = uispinner(app.ParameterPanel);
            app.zSpinner.ValueChangingFcn = createCallbackFcn(app, @zSpinnerValueChanging, true);
            app.zSpinner.Limits = [-10 10];
            app.zSpinnerLabel = uilabel(app.ParameterPanel);
            app.zSpinnerLabel.Text = 'z';
            app.phiSpinner = uispinner(app.ParameterPanel);
            app.phiSpinner.ValueChangingFcn = createCallbackFcn(app, @phiSpinnerValueChanging, true);
            app.phiSpinner.Limits = [-180 180];
            app.phiSpinnerLabel = uilabel(app.ParameterPanel);
            app.phiSpinnerLabel.Text = 'phi';
            app.thetaSpinner = uispinner(app.ParameterPanel);
            app.thetaSpinner.ValueChangingFcn = createCallbackFcn(app, @thetaSpinnerValueChanging, true);
            app.thetaSpinner.Limits = [-180 180];
            app.thetaSpinnerLabel = uilabel(app.ParameterPanel);
            app.thetaSpinnerLabel.Text = 'theta';
            app.psiSpinner = uispinner(app.ParameterPanel);
            app.psiSpinner.ValueChangingFcn = createCallbackFcn(app, @psiSpinnerValueChanging, true);
            app.psiSpinner.Limits = [-180 180];
            app.psiSpinnerLabel = uilabel(app.ParameterPanel);
            app.psiSpinnerLabel.Text = 'psi';
            app.ResetButton = uibutton(app.ParameterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = rotation_matrix

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end